classdef Calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RestablecervaloresButton       matlab.ui.control.Button
        CalcularButton                 matlab.ui.control.Button
        PrecisindexLabel               matlab.ui.control.Label
        numPointsEditField             matlab.ui.control.NumericEditField
        numPointsEditFieldLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        r_femEditField                 matlab.ui.control.NumericEditField
        r_femLabel                     matlab.ui.control.Label
        l_femEditField                 matlab.ui.control.NumericEditField
        l_femEditFieldLabel            matlab.ui.control.Label
        r_cmEditField                  matlab.ui.control.NumericEditField
        r_cmEditFieldLabel             matlab.ui.control.Label
        h_cmEditField                  matlab.ui.control.NumericEditField
        h_cmEditFieldLabel             matlab.ui.control.Label
        ValoresgeomtricosLabel         matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        I_ccAEditField                 matlab.ui.control.NumericEditField
        I_ccAEditFieldLabel            matlab.ui.control.Label
        ValoreselectromagnticosLabel   matlab.ui.control.Label
        mu_feEditField                 matlab.ui.control.NumericEditField
        mu_feEditFieldLabel            matlab.ui.control.Label
        mu0EditField                   matlab.ui.control.EditField
        mu0EditFieldLabel              matlab.ui.control.Label
        LanzaderaelectromagnticaLabel  matlab.ui.control.Label
        FuerzaAxes                     matlab.ui.control.UIAxes
        inductionAxes                  matlab.ui.control.UIAxes
        reluctancesAxes                matlab.ui.control.UIAxes
        deltamuAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function Calcular(app, event)
            cla(app.deltamuAxes);
            cla(app.inductionAxes);
            cla(app.reluctancesAxes);
            cla(app.FuerzaAxes);

            % Electromagnetic properties
            mu0 = 4 * pi * 10^-7; % Permeability of free space in H/m
            mu_fe = app.mu_feEditField.Value; % Relative permeability of the iron bar
            N = app.NEditField.Value; % Number of turns
            I_c = app.I_ccAEditField.Value; % Coil current [A]
            
            % Geometry
            h_c = app.h_cmEditField.Value; % Coil length [m]
            r_c = app.r_cmEditField.Value; % Coil external radius [m]
            l_fe = app.l_femEditField.Value; % Length of the iron bar [m]
            r_fe = app.r_femEditField.Value; % Bar radius [m]
            
            S_c = pi * r_c ^ 2; % Cross-sectional area of the core [m^2]
            S_bar = pi * r_fe ^ 2; % Cross-sectional area of the iron bar [m^2]
            S_phi = 1.5 * S_c; % Cross-sectional area of the flux region [
            
            % Space vectors
            num_points = app.numPointsEditField.Value;
            x = linspace(0.1 * l_fe, h_c, num_points); % Movement region
            t = linspace(0, h_c, num_points); % Auxiliar x

            delta_mur = zeros(num_points);
            for i = 1:num_points
                delta_mur(i) = mu_fe * x(i) / h_c;
            end
            app.deltamuAxes.XLim = [min(x), max(x)];
            plot(app.deltamuAxes, x, delta_mur, 'LineWidth', 2);
            
            % Pre-allocation
            R_core = zeros(size(x));
            R_B = zeros(size(x));
            R_phi = zeros(size(x));
            R_total = zeros(size(x));
            Phi = zeros(size(x));
            B = zeros(size(x));
            F = zeros(size(x));

            % Calculate reluctance, flux, and induction for each position
            for i = 1:length(x)
                R_core(i) = h_c / (mu0 * S_c);
                R_B(i) = l_fe * (1 - t(i)/l_fe) / (mu0 * (mu_fe - delta_mur(i)) * S_bar);
                R_phi(i) = (h_c + l_fe - t(i)) / (mu0 * delta_mur(i) * S_phi);
                R_total(i) = R_core(i) + R_B(i) + R_phi(i);
                
                % Calculate flux
                Phi(i) = (N * I_c) / R_total(i);
                
                % Calculate magnetic induction
                B(i) = Phi(i) / S_bar;
                
                % Calculate force
                F(i) = 0.5 * B(i)^2 * S_bar / mu0;
            end
            
            % Reluctance plot
            hold(app.reluctancesAxes, 'on');
            plot(app.reluctancesAxes, x, R_core, 'r', 'DisplayName', 'R_{core}');
            plot(app.reluctancesAxes, x, R_B, 'g', 'DisplayName', 'R_{bar}');
            plot(app.reluctancesAxes, x, R_phi, 'b', 'DisplayName', 'R_{phi}');
            plot(app.reluctancesAxes, x, R_total, 'k', 'DisplayName', 'R_{total}');
            legend(app.reluctancesAxes, 'Location', 'best');
            hold(app.reluctancesAxes, 'off');
                
            % Induction and force plot
            plot(app.inductionAxes, x, B, 'r');
            plot(app.FuerzaAxes, x, F, 'r');

        end

        % Button pushed function: RestablecervaloresButton
        function Restablecer(app, event)
            % Electromagnetic properties
            app.mu_feEditField.Value = 5000; % Relative permeability of the iron bar
            app.NEditField.Value = 500; % Number of turns
            app.I_ccAEditField.Value = 3.65; % Coil current [A]
            
            % Geometry
            app.h_cmEditField.Value = 0.05321; % Coil length [m]
            app.r_cmEditField.Value = 0.01064; % Coil external radius [m]
            app.l_femEditField.Value = 0.096; % Length of the iron bar [m]
            app.r_femEditField.Value = 0.003045; % Bar radius [m]

            % X and plots
            app.numPointsEditField.Value = 100;
            cla(app.deltamuAxes);
            cla(app.inductionAxes);
            cla(app.reluctancesAxes);
            cla(app.FuerzaAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.deltamuAxes = uiaxes(app.UIFigure);
            title(app.deltamuAxes, 'Δμ vs x')
            xlabel(app.deltamuAxes, 'x')
            ylabel(app.deltamuAxes, 'Δμ')
            zlabel(app.deltamuAxes, 'Z')
            app.reluctancesAxes = uiaxes(app.UIFigure);
            title(app.reluctancesAxes, 'Reluctancias vs x')
            xlabel(app.reluctancesAxes, 'x')
            ylabel(app.reluctancesAxes, 'Reluctancias')
            zlabel(app.reluctancesAxes, 'Z')
            app.inductionAxes = uiaxes(app.UIFigure);
            title(app.inductionAxes, 'Inducción magnética vs x')
            xlabel(app.inductionAxes, 'x')
            ylabel(app.inductionAxes, 'Inducción [T]')
            zlabel(app.inductionAxes, 'Z')
            app.FuerzaAxes = uiaxes(app.UIFigure);
            title(app.FuerzaAxes, 'Fuerza vs x')
            xlabel(app.FuerzaAxes, 'x')
            ylabel(app.FuerzaAxes, 'Fuerza [N]')
            zlabel(app.FuerzaAxes, 'Z')
            app.LanzaderaelectromagnticaLabel = uilabel(app.UIFigure);
            app.LanzaderaelectromagnticaLabel.Text = 'Lanzadera electromagnética';
            app.mu0EditFieldLabel = uilabel(app.UIFigure);
            app.mu0EditFieldLabel.Text = 'mu0';
            app.mu0EditField = uieditfield(app.UIFigure, 'text');
            app.mu0EditField.Editable = 'off';
            app.mu0EditField.Value = '4π*10^-7';
            app.mu_feEditFieldLabel = uilabel(app.UIFigure);
            app.mu_feEditFieldLabel.Text = 'mu_fe';
            app.mu_feEditField = uieditfield(app.UIFigure, 'numeric');
            app.mu_feEditField.Value = 5000;
            app.ValoreselectromagnticosLabel = uilabel(app.UIFigure);
            app.ValoreselectromagnticosLabel.Text = 'Valores electromagnéticos';
            app.I_ccAEditFieldLabel = uilabel(app.UIFigure);
            app.I_ccAEditFieldLabel.Text = 'I_cc [A]';
            app.I_ccAEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_ccAEditField.Value = 3.65;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 500;
            app.ValoresgeomtricosLabel = uilabel(app.UIFigure);
            app.ValoresgeomtricosLabel.Text = 'Valores geométricos';
            app.h_cmEditFieldLabel = uilabel(app.UIFigure);
            app.h_cmEditFieldLabel.Text = 'h_c [m]';
            app.h_cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.h_cmEditField.Limits = [0 Inf];
            app.h_cmEditField.Value = 0.05321;
            app.r_cmEditFieldLabel = uilabel(app.UIFigure);
            app.r_cmEditFieldLabel.Text = 'r_c [m]';
            app.r_cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.r_cmEditField.Limits = [0 Inf];
            app.r_cmEditField.Value = 0.01064;
            app.l_femEditFieldLabel = uilabel(app.UIFigure);
            app.l_femEditFieldLabel.Text = 'l_fe [m]';
            app.l_femEditField = uieditfield(app.UIFigure, 'numeric');
            app.l_femEditField.Limits = [0 Inf];
            app.l_femEditField.Value = 0.096;
            app.r_femLabel = uilabel(app.UIFigure);
            app.r_femLabel.Text = 'r_fe [m]';
            app.r_femEditField = uieditfield(app.UIFigure, 'numeric');
            app.r_femEditField.Limits = [0 Inf];
            app.r_femEditField.Value = 0.003045;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Geometry.png');
            app.numPointsEditFieldLabel = uilabel(app.UIFigure);
            app.numPointsEditFieldLabel.Text = 'numPoints';
            app.numPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.numPointsEditField.Limits = [0 Inf];
            app.numPointsEditField.Value = 500;
            app.PrecisindexLabel = uilabel(app.UIFigure);
            app.PrecisindexLabel.Text = 'Precisión de x';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @Calcular, true);
            app.CalcularButton.Text = 'Calcular';
            app.RestablecervaloresButton = uibutton(app.UIFigure, 'push');
            app.RestablecervaloresButton.ButtonPushedFcn = createCallbackFcn(app, @Restablecer, true);
            app.RestablecervaloresButton.Text = 'Restablecer valores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end